classdef MAIN_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DreamCatcherProUIFigure         matlab.ui.Figure
        MenuHelp                        matlab.ui.container.Menu
        HowtousetheappMenu              matlab.ui.container.Menu
        MenuAbout                       matlab.ui.container.Menu
        VersionMenu                     matlab.ui.container.Menu
        AuthorMenu                      matlab.ui.container.Menu
        MainTabGroup                    matlab.ui.container.TabGroup
        tab_importdata                  matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ChannelstoloadEditField         matlab.ui.control.EditField
        ChannelstoloadEditFieldLabel    matlab.ui.control.Label
        RunImportData_Button            matlab.ui.control.Button
        RereferenceTab                  matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        ReferncechannelsEditField       matlab.ui.control.EditField
        ReferncechannelsEditFieldLabel  matlab.ui.control.Label
        RunRereferenceButton            matlab.ui.control.Button
        FilterTab                       matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        LowpassfilterorderEditField     matlab.ui.control.NumericEditField
        LowpassfilterorderEditFieldLabel  matlab.ui.control.Label
        HighpassfilterorderEditField    matlab.ui.control.NumericEditField
        HighpassfilterorderEditFieldLabel  matlab.ui.control.Label
        LowpassfilterboundryHzEditField  matlab.ui.control.NumericEditField
        LowpassfilterboundryHzEditFieldLabel  matlab.ui.control.Label
        HighpassfilterboundryHzEditField  matlab.ui.control.NumericEditField
        HighpassfilterboundryHzEditFieldLabel  matlab.ui.control.Label
        RunFilterButton                 matlab.ui.control.Button
        SegmentTab                      matlab.ui.container.Tab
        ThissectionLabel                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        EventstouseforsegmentationEditField  matlab.ui.control.EditField
        EventstouseforsegmentationEditFieldLabel  matlab.ui.control.Label
        SegmentlengthsecEditField       matlab.ui.control.NumericEditField
        SegmentlengthsecEditFieldLabel  matlab.ui.control.Label
        CleandataTab                    matlab.ui.container.Tab
        RunButton_2                     matlab.ui.control.Button
        CleanDataParametersTab          matlab.ui.container.TabGroup
        VisualinspectionTab             matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        VisualinspectionmodeButtonGroup  matlab.ui.container.ButtonGroup
        RemoveeachchannelindividuallyButton  matlab.ui.control.ToggleButton
        RemoveentiresegmentsButton      matlab.ui.control.ToggleButton
        NumberofsegmentstodisplayEditField  matlab.ui.control.EditField
        NumberofsegmentstodisplayEditFieldLabel  matlab.ui.control.Label
        CleansignalrangeuVEditField     matlab.ui.control.EditField
        CleansignalrangeuVEditFieldLabel  matlab.ui.control.Label
        AmplitudetodisplayuVEditField   matlab.ui.control.EditField
        AmplitudetodisplayuVEditFieldLabel  matlab.ui.control.Label
        AmplitudethresholdTab           matlab.ui.container.Tab
        Label_5                         matlab.ui.control.Label
        UpperamplitudelimituVEditField  matlab.ui.control.EditField
        UpperamplitudelimituVEditFieldLabel  matlab.ui.control.Label
        LoweramplitudelimituVEditField  matlab.ui.control.EditField
        LoweramplitudelimituVEditFieldLabel  matlab.ui.control.Label
        NumberofepochstodisplayEditField_2  matlab.ui.control.EditField
        NumberofepochstodisplayEditField_2Label  matlab.ui.control.Label
        AmplitudetodisplayuVEditField_2  matlab.ui.control.EditField
        AmplitudetodisplayuVEditField_2Label  matlab.ui.control.Label
        CalculatePowerTab               matlab.ui.container.Tab
        Label_6                         matlab.ui.control.Label
        PlotEEGspectrumforeachchannelCheckBox  matlab.ui.control.CheckBox
        WindowlengthsecEditField        matlab.ui.control.NumericEditField
        WindowlengthsecEditFieldLabel   matlab.ui.control.Label
        RunPower_Button                 matlab.ui.control.Button
        UpperboundHzLabel               matlab.ui.control.Label
        LowerboundHzLabel_2             matlab.ui.control.Label
        DeltaLowerEditField             matlab.ui.control.EditField
        DeltaUpperEditField             matlab.ui.control.EditField
        ThetaLowerEditField             matlab.ui.control.EditField
        ThetaUpperEditField             matlab.ui.control.EditField
        AlphaLowerEditField             matlab.ui.control.EditField
        AlphaUpperEditField             matlab.ui.control.EditField
        SigmaLowerEditField             matlab.ui.control.EditField
        SigmaUpperEditField             matlab.ui.control.EditField
        BetaLowerEditField              matlab.ui.control.EditField
        BetaEditFieldLabel              matlab.ui.control.Label
        SigmaEditFieldLabel             matlab.ui.control.Label
        AlphaEditFieldLabel             matlab.ui.control.Label
        ThetaEditFieldLabel             matlab.ui.control.Label
        BetaUpperEditField              matlab.ui.control.EditField
        DeltaEditFieldLabel             matlab.ui.control.Label
        ListBox_dataFiles               matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        Button_LoadData                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        datafiles = {}; % An char array containg datafile on which processing is performed
        datapath = {};
    end
    
    methods (Access = public)
        
        function outputcell = editField_to_cell(app, EditFieldConent)
            outputcell = strrep(EditFieldConent, "'", "");
            outputcell = strrep(outputcell, ";", ",");
            outputcell = strrep(outputcell, " ", "");            
            outputcell = split(outputcell, ',');
            % Convert string array to cell array
            outputcell = cellstr(outputcell);            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_LoadData
        function LoadDataButtonPushed(app, event)
            selectedTab = app.MainTabGroup.SelectedTab.Title;
            switch selectedTab
                case 'Import EEG data'
                    directory = uigetdir('', "Select folder containing raw EEG data.");
                    txtDataFiles = dir(fullfile(directory,'*.txt'));
                    
                    % If user selected a folder containing subfolders for
                    % each subject
                    if isempty(txtDataFiles)
                        directoryContents = dir(directory);
                        folderNames = {directoryContents([directoryContents.isdir]).name};
                        folderNames = folderNames(~ismember(folderNames,{'.','..'}));
                    
                        for i = 1:length(folderNames)
                            if ~isfolder(fullfile(directory, folderNames{i}))
                                errordlg( ...
                                    sprintf('Oy! The input folder %s does not seem to be a folder containing raw EEG data!', ...
                                    fullfile(directory, folderNames{i})) ...
                                    )
                            end
                        end
                        
                        app.datafiles = folderNames;
                        app.datapath = directory;
                    
                    % If user selected a folder containing data for the
                    % single subject
                    else                    
                        [parentFolder, datafolder, b] = fileparts(directory); 
                        app.datafiles= {datafolder};
                        app.datapath = parentFolder;
                    end

                otherwise
                    % open a popup window to select files for processing. 
                    [app.datafiles, app.datapath] = uigetfile({'*.txt; *.set; *.erp'}, 'MultiSelect','on');
                    if ischar(app.datafiles)
                        app.datafiles = {app.datafiles};
                    end
                    % app.datafiles = cellfun(@(str) [path, str], files, 'UniformOutput', false);
            end
            if ~isempty(app.datafiles)
                app.Button_LoadData.BackgroundColor = [0,1,0]; % Green color
                app.ListBox_dataFiles.Items = app.datafiles;
            end


        end

        % Button pushed function: RunImportData_Button
        function RunImportDataButtonPushed(app, event)
            % Get the value from the EditField
            channels_to_load = editField_to_cell(app, app.ChannelstoloadEditField.Value);
            % Run function that loads txt EEG files into .set files (eeglab)
            if isempty(app.datafiles)
                warndlg('Whoops! Load data to be processed first!')
            else
                loadDataToEeglab(app.datafiles, app.datapath, channels_to_load)
            end
            
        end

        % Button pushed function: RunRereferenceButton
        function RunRereferenceButtonPushed(app, event)
            % Get the value from the EditField
            reference_channels = editField_to_cell(app, app.ReferncechannelsEditField.Value);
            
            if isempty(app.datafiles)
                warndlg('Whoops! Load data to be processed first!')
            else
                reference_data(app.datafiles, app.datapath, reference_channels)
            end

        end

        % Button pushed function: RunFilterButton
        function RunFilterButtonPushed(app, event)
            lowpass_cutoff = app.LowpassfilterboundryHzEditField.Value;
            lowpass_order = app.LowpassfilterorderEditField.Value;
            highpass_cutoff = app.HighpassfilterboundryHzEditField.Value;
            highpass_order = app.HighpassfilterorderEditField.Value;
            
            if isempty(app.datafiles)
                warndlg('Whoops! Load data to be processed first!')
            else
                applyFilters(app.datafiles, app.datapath, ...
                             highpass_cutoff, highpass_order, ...
                             lowpass_cutoff, lowpass_order)
            end
        end

        % Button pushed function: RunButton
        function RunSegmentButtonPushed(app, event)
            sleepPhases = editField_to_cell(app, app.EventstouseforsegmentationEditField.Value);
            epochLen = app.SegmentlengthsecEditField.Value;

            if isempty(app.datafiles)
                warndlg('Whoops! Load data to be processed first!')
            else
                epochEEG(app.datafiles, app.datapath, ...
                         sleepPhases, ...
                         epochLen)
            end
        end

        % Selection change function: MainTabGroup
        function TabSelectionChangedCallback(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            % Each time the tab is changed a list of data files to process
            % should be cleared
            app.datafiles = {};
            app.datapath = {};
            app.ListBox_dataFiles.Items = {};
            app.Button_LoadData.BackgroundColor = [0.64,0.08,0.18]; % red color
        end

        % Button pushed function: RunButton_2
        function RunCleanButtonPushed(app, event)
            switch app.CleanDataParametersTab.SelectedTab.Title
                case 'Visual inspection'
                    amplitudeToDisplay = str2double(app.AmplitudetodisplayuVEditField.Value);
                    numOfEpochsToDisplay = str2double(app.NumberofsegmentstodisplayEditField.Value);
                    clean_signal_max_amplitude = [-1*str2double(app.CleansignalrangeuVEditField.Value), 
                                                  str2double(app.CleansignalrangeuVEditField.Value)];
                   
                    switch app.VisualinspectionmodeButtonGroup.SelectedObject.Text   
                        case 'Remove each channel individually'
                            remove_mode = 'single_channel';
                        case 'Remove entire segments'
                            remove_mode = 'entire_epoch';
                    end
                    if isempty(app.datafiles)
                        warndlg('Whoops! Load data to be processed first!')
                    else
                        removeNoisyEpochs_visualInspection( ...
                                                app.datafiles, app.datapath, ...
                                                remove_mode,...
                                                amplitudeToDisplay, ...
                                                numOfEpochsToDisplay, ...
                                                clean_signal_max_amplitude)
                    end
                case 'Amplitude threshold'
                    amplitudeToDisplay = str2double(app.AmplitudetodisplayuVEditField_2.Value);
                    numOfEpochsToDisplay = str2double(app.NumberofepochstodisplayEditField_2.Value);
                    lowerAmplitudeLimit = str2double(app.LoweramplitudelimituVEditField.Value);
                    upperAmplitudeLimit = str2double(app.UpperamplitudelimituVEditField.Value);
                    if isempty(app.datafiles)
                        warndlg('Whoops! Load data to be processed first!')
                    else
                        removeNoisyEpochs_amplitudeThreshold(app.datafiles, app.datapath, ...
                                                             amplitudeToDisplay, ...
                                                             numOfEpochsToDisplay, ...
                                                             lowerAmplitudeLimit, ...
                                                             upperAmplitudeLimit)
                    end
            end
            % Clean list of datafiles to avoid running two types of
            % cleaning on the same files
            app.datafiles = {};
            app.datapath = {};
            app.ListBox_dataFiles.Items = {};
            app.Button_LoadData.BackgroundColor = [0.64,0.08,0.18]; % red color

            
        end

        % Selection changed function: VisualinspectionmodeButtonGroup
        function VisualInspectionModeButtonPushed(app, event)
            single_channel_button= findobj(app.VisualinspectionmodeButtonGroup.Children, 'Tag', 'single_channel_button');
            entire_epoch_button= findobj(app.VisualinspectionmodeButtonGroup.Children, 'Tag', 'entire_epoch_button');
            switch event.NewValue.Text
                case 'Remove each channel individually'
                    single_channel_button.BackgroundColor = [0.39, 0.83, 0.07];
                    entire_epoch_button.BackgroundColor = [0.8, 0.8, 0.8];
                case 'Remove entire segments'
                    single_channel_button.BackgroundColor = [0.8, 0.8, 0.8];
                    entire_epoch_button.BackgroundColor = [0.39, 0.83, 0.07];
            end
        end

        % Menu selected function: AuthorMenu
        function AuthorMenuPushed(app, event)
            msgbox(["Author: "; ...
                    "Natan Napiórkowski"; ...

                    "natan.napiorkowski@gmail.com"]);

        end

        % Menu selected function: VersionMenu
        function VersionMenuPushed(app, event)
            msgbox(["App version: 20250115"; " " ;...
                    "Details and changelog:";...
                    "Initial alpha, buggy, experimental version..."; ...
                    "Enabled selecting only one datafolder in the tab 'Import EEG data'";...
                    "20250115: Fixed a bug in loadDataToEeglab. If an event file is missing it is skipped."]);
        end

        % Menu selected function: HowtousetheappMenu
        function HowToUseAppMenuPushed(app, event)
            
        end

        % Button pushed function: RunPower_Button
        function RunPowerButtonPushed(app, event)
            freq_bands = {};
            freq_bands.delta = [str2num(app.DeltaLowerEditField.Value), ...
                                str2num(app.DeltaUpperEditField.Value)];
            freq_bands.theta = [str2num(app.ThetaLowerEditField.Value), ...
                                str2num(app.ThetaUpperEditField.Value)];
            freq_bands.alpha = [str2num(app.AlphaLowerEditField.Value), ...
                                str2num(app.AlphaUpperEditField.Value)];
            freq_bands.sigma = [str2num(app.SigmaLowerEditField.Value), ...
                                str2num(app.SigmaUpperEditField.Value)];
            freq_bands.beta  = [str2num(app.BetaLowerEditField.Value), ...
                                str2num(app.BetaUpperEditField.Value)];
            FFT_winlen = app.WindowlengthsecEditField.Value;
            

            if isempty(app.datafiles)
                warndlg('Whoops! Load data to be processed first!')
            else
                % Calculate power
                FFT_Welch(app.datafiles, app.datapath, ...
                          FFT_winlen, ...
                          freq_bands)
                
                % Save power values to csv files
                saveBandsPowerToFile_longFormat(fullfile(app.datapath, 'EEG_power'), ...
                                                'mean', ...
                                                'absolute')
                saveBandsPowerToFile_longFormat(fullfile(app.datapath, 'EEG_power'), ...
                                                'mean', ...
                                                'relative')
                saveBandsPowerToFile_wideFormat(fullfile(app.datapath, 'EEG_power'), ...
                                                'mean', ...
                                                'absolute')
                saveBandsPowerToFile_wideFormat(fullfile(app.datapath, 'EEG_power'), ...
                                                'mean', ...
                                                'relative')

                % Plot power
                if app.PlotEEGspectrumforeachchannelCheckBox.Value
                    fontsize = 15;
                    overlayBands = true;
                    for plotType = ["PSD", "dB"]
                    for abs_or_rel = ["relative","absolute"]
                        plotFFT(fullfile(app.datapath, 'EEG_power'), ...
                                app.datafiles, ...
                                plotType, ...
                                fontsize, ...
                                abs_or_rel , ...
                                overlayBands)
                    end
                    end
                end
            end
            % Clean list of datafiles to avoid running two types of
            % cleaning on the same files
            app.datafiles = {};
            app.datapath = {};
            app.ListBox_dataFiles.Items = {};
            app.Button_LoadData.BackgroundColor = [0.64,0.08,0.18]; % red color

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DreamCatcherProUIFigure = uifigure('Visible', 'off');
            app.DreamCatcherProUIFigure.Name = 'DreamCatcher Pro';
            app.MenuHelp = uimenu(app.DreamCatcherProUIFigure);
            app.MenuHelp.Text = 'Help';
            app.HowtousetheappMenu = uimenu(app.MenuHelp);
            app.HowtousetheappMenu.MenuSelectedFcn = createCallbackFcn(app, @HowToUseAppMenuPushed, true);
            app.HowtousetheappMenu.Text = 'How to use the app';
            app.MenuAbout = uimenu(app.DreamCatcherProUIFigure);
            app.MenuAbout.Text = 'About';
            app.VersionMenu = uimenu(app.MenuAbout);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuPushed, true);
            app.VersionMenu.Text = 'Version';
            app.AuthorMenu = uimenu(app.MenuAbout);
            app.AuthorMenu.MenuSelectedFcn = createCallbackFcn(app, @AuthorMenuPushed, true);
            app.AuthorMenu.Text = 'Author';
            app.Button_LoadData = uibutton(app.DreamCatcherProUIFigure, 'push');
            app.Button_LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.Button_LoadData.Text = 'Load files to process';
            app.ListBoxLabel = uilabel(app.DreamCatcherProUIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox_dataFiles = uilistbox(app.DreamCatcherProUIFigure);
            app.ListBox_dataFiles.Items = {};
            app.ListBox_dataFiles.Value = {};
            app.MainTabGroup = uitabgroup(app.DreamCatcherProUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabSelectionChangedCallback, true);
            app.tab_importdata = uitab(app.MainTabGroup);
            app.tab_importdata.Title = 'Import EEG data';
            app.RunImportData_Button = uibutton(app.tab_importdata, 'push');
            app.RunImportData_Button.ButtonPushedFcn = createCallbackFcn(app, @RunImportDataButtonPushed, true);
            app.RunImportData_Button.Text = 'Run!';
            app.ChannelstoloadEditFieldLabel = uilabel(app.tab_importdata);
            app.ChannelstoloadEditFieldLabel.Text = 'Channels to  load:';
            app.ChannelstoloadEditField = uieditfield(app.tab_importdata, 'text');
            app.ChannelstoloadEditField.Value = '''A1'', ''A2'', ''F3'', ''F4'', ''C3'', ''C4'', ''O1'', ''O2''';
            app.Label = uilabel(app.tab_importdata);
            app.Label.Text = {' This script converts .txt EEG data files into a MATLAB-friendly format. This app assumes that data files of different subjects are stored in separate folders. Each subject subfolder must not contain any further subfolders.'; ''; ' Step 1: Specifying storage location and file name. Press a red button "Load files to process" and a pop-up window will appear prompting you to specify the main folder, where the subfolders with EEG files of all subjects are stored (one subfolder per subject). After selection these locations should be displayed in the white box. After pressing the green button "Run!" a second pop-up window will requests to input the desired location for the converted EEG files.'; ''; ' Step 2: Importing and converting EEG data files to EEGLAB format.'; ' Files containing EEG data (identified by having a channel name in the file name) will imported and converted into a format supported by EEGLAB. You can specify which channels should be loaded in the section below.'; ''; ' Step 3: Loading event data files and merging them into "Events.csv".'; 'Data from the events files (e.g., Schlafprofil) are loaded and merged into a common file named "Events.csv". This conversion enables the loading of events into EEGLAB along with EEG data.'; ''; ' Step 4: Merging events with EEG data and saving to specified location.'; 'All events are merged with the EEG data and saved into the location specified in Step 1. The resulting file contains both EEG data and associated events, facilitating further analysis and processing.'};
            app.RereferenceTab = uitab(app.MainTabGroup);
            app.RereferenceTab.Title = 'Re-reference';
            app.RunRereferenceButton = uibutton(app.RereferenceTab, 'push');
            app.RunRereferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RunRereferenceButtonPushed, true);
            app.RunRereferenceButton.Text = 'Run!';
            app.ReferncechannelsEditFieldLabel = uilabel(app.RereferenceTab);
            app.ReferncechannelsEditFieldLabel.Text = 'Refernce channels:';
            app.ReferncechannelsEditField = uieditfield(app.RereferenceTab, 'text');
            app.ReferncechannelsEditField.Value = '''A1'', ''A2''';
            app.Label_2 = uilabel(app.RereferenceTab);
            app.Label_2.Text = {'This section lets you change the reference of the EEG data.'; ''; 'Step 1: Use the button on the left to select the .set files you want to process.'; 'Step 2: Enter the channels that should be the new reference channels in the field below.'; 'Step 3: Click the green "Run!" button.'; ''; 'The re-referenced files will be saved in a new folder named "Rereferenced_to_<reference channels>" within the folder containing the original data you loaded in the Step 1.'};
            app.FilterTab = uitab(app.MainTabGroup);
            app.FilterTab.Title = 'Filter';
            app.RunFilterButton = uibutton(app.FilterTab, 'push');
            app.RunFilterButton.ButtonPushedFcn = createCallbackFcn(app, @RunFilterButtonPushed, true);
            app.RunFilterButton.Text = 'Run!';
            app.HighpassfilterboundryHzEditFieldLabel = uilabel(app.FilterTab);
            app.HighpassfilterboundryHzEditFieldLabel.Text = 'High pass filter boundry [Hz]';
            app.HighpassfilterboundryHzEditField = uieditfield(app.FilterTab, 'numeric');
            app.HighpassfilterboundryHzEditField.Value = 0.5;
            app.LowpassfilterboundryHzEditFieldLabel = uilabel(app.FilterTab);
            app.LowpassfilterboundryHzEditFieldLabel.Text = 'Low pass filter boundry [Hz]';
            app.LowpassfilterboundryHzEditField = uieditfield(app.FilterTab, 'numeric');
            app.LowpassfilterboundryHzEditField.Value = 35;
            app.HighpassfilterorderEditFieldLabel = uilabel(app.FilterTab);
            app.HighpassfilterorderEditFieldLabel.Text = 'High pass filter order';
            app.HighpassfilterorderEditField = uieditfield(app.FilterTab, 'numeric');
            app.HighpassfilterorderEditField.Value = 6;
            app.LowpassfilterorderEditFieldLabel = uilabel(app.FilterTab);
            app.LowpassfilterorderEditFieldLabel.Text = 'Low pass filter order';
            app.LowpassfilterorderEditField = uieditfield(app.FilterTab, 'numeric');
            app.LowpassfilterorderEditField.Value = 6;
            app.Label_3 = uilabel(app.FilterTab);
            app.Label_3.Text = {'This section filters the data.'; ''; 'You can specify the low-pass and high-pass cutoff frequencies, as well as the filter order. All filters used are Infinite Impulse Response (IR) Butterworth filters.'; ''; 'The filtered files will be saved in a new folder named "Filter_<high pass frequency>_<low pass frequency>" within the folder containing the data you just loaded.'};
            app.SegmentTab = uitab(app.MainTabGroup);
            app.SegmentTab.Title = 'Segment';
            app.SegmentlengthsecEditFieldLabel = uilabel(app.SegmentTab);
            app.SegmentlengthsecEditFieldLabel.Text = 'Segment length [sec]';
            app.SegmentlengthsecEditField = uieditfield(app.SegmentTab, 'numeric');
            app.SegmentlengthsecEditField.Value = 30;
            app.EventstouseforsegmentationEditFieldLabel = uilabel(app.SegmentTab);
            app.EventstouseforsegmentationEditFieldLabel.Text = 'Events to use for segmentation';
            app.EventstouseforsegmentationEditField = uieditfield(app.SegmentTab, 'text');
            app.EventstouseforsegmentationEditField.Value = '''N1'', ''N2'', ''N3'',''Rem'', ''Wach''';
            app.RunButton = uibutton(app.SegmentTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSegmentButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.ThissectionLabel = uilabel(app.SegmentTab);
            app.ThissectionLabel.Text = {'This section divides the continuous EEG data into segments.'; ''; 'Below, you can specify:'; ''; '1)    The EEG events to use for segmentation. Continuous data will be segmented around these events only.'; '2)    The length of each segment in seconds. Each segment will begin at the specified event and will last for the given number of seconds.'};
            app.CleandataTab = uitab(app.MainTabGroup);
            app.CleandataTab.Title = 'Clean data';
            app.CleanDataParametersTab = uitabgroup(app.CleandataTab);
            app.VisualinspectionTab = uitab(app.CleanDataParametersTab);
            app.VisualinspectionTab.Title = 'Visual inspection';
            app.AmplitudetodisplayuVEditFieldLabel = uilabel(app.VisualinspectionTab);
            app.AmplitudetodisplayuVEditFieldLabel.Text = 'Amplitude to display [uV]';
            app.AmplitudetodisplayuVEditField = uieditfield(app.VisualinspectionTab, 'text');
            app.AmplitudetodisplayuVEditField.Value = '150';
            app.CleansignalrangeuVEditFieldLabel = uilabel(app.VisualinspectionTab);
            app.CleansignalrangeuVEditFieldLabel.Text = 'Clean signal range [uV]';
            app.CleansignalrangeuVEditField = uieditfield(app.VisualinspectionTab, 'text');
            app.CleansignalrangeuVEditField.Value = '200';
            app.NumberofsegmentstodisplayEditFieldLabel = uilabel(app.VisualinspectionTab);
            app.NumberofsegmentstodisplayEditFieldLabel.Text = 'Number of segments to display';
            app.NumberofsegmentstodisplayEditField = uieditfield(app.VisualinspectionTab, 'text');
            app.NumberofsegmentstodisplayEditField.Value = '15';
            app.VisualinspectionmodeButtonGroup = uibuttongroup(app.VisualinspectionTab);
            app.VisualinspectionmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualInspectionModeButtonPushed, true);
            app.VisualinspectionmodeButtonGroup.Title = 'Visual inspection mode';
            app.RemoveentiresegmentsButton = uitogglebutton(app.VisualinspectionmodeButtonGroup);
            app.RemoveentiresegmentsButton.Tag = 'entire_epoch_button';
            app.RemoveentiresegmentsButton.Text = 'Remove entire segments';
            app.RemoveeachchannelindividuallyButton = uitogglebutton(app.VisualinspectionmodeButtonGroup);
            app.RemoveeachchannelindividuallyButton.Tag = 'single_channel_button';
            app.RemoveeachchannelindividuallyButton.Text = 'Remove each channel individually';
            app.RemoveeachchannelindividuallyButton.Value = true;
            app.Label_4 = uilabel(app.VisualinspectionTab);
            app.Label_4.Text = {'This section allows you to visually inspect EEG data and remove any noisy data. Once you press the green "Run!" button, a new window will appear displaying your data. Use it to manually mark (highlight red) fragments of data to reject. After you press the button "REMOVE", the window will close and the cleaned data will be saved.'; ''; 'You can specify the following parameters:'; ''; '1) Amplitude to display [uV]: Determine the displayed amplitude. If this value is too low, signals from neighboring channels might overlap, making it difficult to discern details on the figure.'; '2) Number of epochs to display: Set the number of segments to display in a single window.'; '3) Clean signal range [uV]: Signal exceeding this value will be highlighted in red for easier identification.'; '4) In the "Visual inspection mode" panel, you can use the buttons to perform the following actions:'; '    Remove individual channels: This option allows you to eliminate each channel separately. It''s useful when you only need to discard data from a malfunctioning electrode without loosing other channels.'; '    Remove entire segments: With a single click, you can delete entire segments. This feature comes in handy when you want to exclude data portions containing global artifacts like body movements.'};
            app.AmplitudethresholdTab = uitab(app.CleanDataParametersTab);
            app.AmplitudethresholdTab.Title = 'Amplitude threshold';
            app.AmplitudetodisplayuVEditField_2Label = uilabel(app.AmplitudethresholdTab);
            app.AmplitudetodisplayuVEditField_2Label.Text = 'Amplitude to display [uV]';
            app.AmplitudetodisplayuVEditField_2 = uieditfield(app.AmplitudethresholdTab, 'text');
            app.AmplitudetodisplayuVEditField_2.Value = '150';
            app.NumberofepochstodisplayEditField_2Label = uilabel(app.AmplitudethresholdTab);
            app.NumberofepochstodisplayEditField_2Label.Text = 'Number of epochs to display';
            app.NumberofepochstodisplayEditField_2 = uieditfield(app.AmplitudethresholdTab, 'text');
            app.NumberofepochstodisplayEditField_2.Value = '15';
            app.LoweramplitudelimituVEditFieldLabel = uilabel(app.AmplitudethresholdTab);
            app.LoweramplitudelimituVEditFieldLabel.Text = 'Lower amplitude limit [uV]';
            app.LoweramplitudelimituVEditField = uieditfield(app.AmplitudethresholdTab, 'text');
            app.LoweramplitudelimituVEditField.Value = '-200';
            app.UpperamplitudelimituVEditFieldLabel = uilabel(app.AmplitudethresholdTab);
            app.UpperamplitudelimituVEditFieldLabel.Text = 'Upper amplitude limit [uV]';
            app.UpperamplitudelimituVEditField = uieditfield(app.AmplitudethresholdTab, 'text');
            app.UpperamplitudelimituVEditField.Value = '200';
            app.Label_5 = uilabel(app.AmplitudethresholdTab);
            app.Label_5.Text = {'This section automates the removal of noisy EEG data based on an amplitude threshold.'; 'After you press the green "Run!" button, a new window will show your data. This window allows you to mark (highlight in red) segments for rejection based on signals exceeding the provided amplitude thresholds.'; ''; 'Once you''ve marked all noisy segments, click the ''Reject'' button. A new window will appear, informing you of the number of rejected segments. You''ll then have the option to adjust the amplitude thresholds if necessary and repeat the process.'; ''; 'You can specify the following parameters:'; '1) Amplitude to display [uV]: Determine the displayed amplitude. If this value is too low, signals from neighboring channels might overlap, making it difficult to discern details on the figure.'; '2) Number of epochs to display: Set the number of segments to display in a single window.'; '3) Lower/Upper amplitude limit [uV]: Signals exceeding these thresholds will be marked for rejection.'};
            app.RunButton_2 = uibutton(app.CleandataTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunCleanButtonPushed, true);
            app.RunButton_2.Text = 'Run!';
            app.CalculatePowerTab = uitab(app.MainTabGroup);
            app.CalculatePowerTab.Title = 'Calculate Power';
            app.DeltaEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.BetaUpperEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.BetaUpperEditField.Tag = 'BetaUpperEditField';
            app.BetaUpperEditField.Value = '30';
            app.ThetaEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.AlphaEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.SigmaEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.BetaEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaLowerEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.BetaLowerEditField.Tag = 'BetaLowerEditField';
            app.BetaLowerEditField.Value = '15';
            app.SigmaUpperEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.SigmaUpperEditField.Tag = 'SigmaUpperEditField';
            app.SigmaUpperEditField.Value = '15';
            app.SigmaLowerEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.SigmaLowerEditField.Tag = 'SigmaLowerEditField';
            app.SigmaLowerEditField.Value = '12';
            app.AlphaUpperEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.AlphaUpperEditField.Tag = 'AlphaUpperEditField';
            app.AlphaUpperEditField.Value = '12';
            app.AlphaLowerEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.AlphaLowerEditField.Tag = 'AlphaLowerEditField';
            app.AlphaLowerEditField.Value = '8';
            app.ThetaUpperEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.ThetaUpperEditField.Tag = 'ThetaUpperEditField';
            app.ThetaUpperEditField.Value = '8';
            app.ThetaLowerEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.ThetaLowerEditField.Tag = 'ThetaLowerEditField';
            app.ThetaLowerEditField.Value = '4';
            app.DeltaUpperEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.DeltaUpperEditField.Tag = 'DeltaUpperEditField';
            app.DeltaUpperEditField.Value = '4';
            app.DeltaLowerEditField = uieditfield(app.CalculatePowerTab, 'text');
            app.DeltaLowerEditField.Tag = 'DeltaLowerEditField';
            app.DeltaLowerEditField.Value = '0.5';
            app.LowerboundHzLabel_2 = uilabel(app.CalculatePowerTab);
            app.LowerboundHzLabel_2.Text = 'Lower bound [Hz]';
            app.UpperboundHzLabel = uilabel(app.CalculatePowerTab);
            app.UpperboundHzLabel.Text = 'Upper bound [Hz]';
            app.RunPower_Button = uibutton(app.CalculatePowerTab, 'push');
            app.RunPower_Button.ButtonPushedFcn = createCallbackFcn(app, @RunPowerButtonPushed, true);
            app.RunPower_Button.Tag = 'RunPower_Button';
            app.RunPower_Button.Text = 'Run!';
            app.WindowlengthsecEditFieldLabel = uilabel(app.CalculatePowerTab);
            app.WindowlengthsecEditFieldLabel.Text = 'Window length [sec]';
            app.WindowlengthsecEditField = uieditfield(app.CalculatePowerTab, 'numeric');
            app.WindowlengthsecEditField.Tag = 'aaaa';
            app.WindowlengthsecEditField.Value = 4;
            app.PlotEEGspectrumforeachchannelCheckBox = uicheckbox(app.CalculatePowerTab);
            app.PlotEEGspectrumforeachchannelCheckBox.Tag = 'PlotEEGpower_tick';
            app.PlotEEGspectrumforeachchannelCheckBox.Text = 'Plot EEG spectrum for each channel';
            app.PlotEEGspectrumforeachchannelCheckBox.Value = true;
            app.Label_6 = uilabel(app.CalculatePowerTab);
            app.Label_6.Text = {'This section performs spectral analysis of trhe segmented EEG data. The procedure utilizes Welch''s method of '; 'spectral density calculation. Refer to https://en.wikipedia.org/wiki/Welch%27s_method for more details. '; 'EEG power is calculated separately for each segment, each sleep phase and each channel.'; 'Furthermore additional metrics are computed:'; '        Average power over left/right channels'; '        Left-over-right channels power ratio'; '        Slow-to-fast ratio ([delta + theta] / [alpha + beta])'; '        Relative power [%]'; '        Log-transformed power'; '        Dominant frequency'; '        Mean power for each frequency band'; 'Specify the following parameters:'; '        Power spectra ranges (in Hz)'; '        Window length in seconds (for Welch''s method)'; '        Whether to generate figures for each subject'; 'The output will be saved in a folder ''EEG_power'' and will consist of: EEG files, csv files containing power values for '; 'all subjects and figures in a png format.'};
            % Show the figure after all components are created
            app.DreamCatcherProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DreamCatcherProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DreamCatcherProUIFigure)
        end
    end
end